classdef cag383_FinalProjectUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        headlightStatus         matlab.ui.control.Label
        headlight               matlab.ui.control.Label
        coolAlarm               matlab.ui.control.Lamp
        tempAlarm               matlab.ui.control.Lamp
        TemperatureGauge        matlab.ui.control.LinearGauge
        TemperatureGaugeLabel   matlab.ui.control.Label
        CoolantLevelGauge       matlab.ui.control.SemicircularGauge
        CoolantLevelGaugeLabel  matlab.ui.control.Label
        distance                matlab.ui.control.Label
        distanceString          matlab.ui.control.Label
        red                     matlab.ui.control.Lamp
        yellow                  matlab.ui.control.Lamp
        green                   matlab.ui.control.Lamp
        MotorSpeedGauge         matlab.ui.control.Gauge
        MotorSpeedGaugeLabel    matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            arduino=serialport("COM3",9600,"Timeout",15);
            pause(1)
            distance = 0;
            red = 0;
            yellow = 0;
            green = 0;
            motorSpeed = 0.0;
            temperature = 0.0;
            temperatureAlarm = 0;
            coolantLevel = 0;
            coolantLevelAlarm = 0;
            headlightStatus = 0;
            while(1)
                flush(arduino);
                write(arduino,2,'string');
                pause(0.1);
                a=read(arduino,33,'string');
                flush(arduino);
                [distance,remain] = strtok(a,',');
                [red,remain] = strtok(remain,',');
                [yellow,remain] = strtok(remain,',');
                [green,remain] = strtok(remain,',');
                [motorSpeed,remain] = strtok(remain,',');
                [temperature,remain] = strtok(remain,',');
                [temperatureAlarm,remain] = strtok(remain,',');
                [coolantLevel,remain] = strtok(remain,',');
                [coolantLevelAlarm,remain] = strtok(remain,',');
                [headlightStatus,remain] = strtok(remain,',');
                pause(0.01)
                app.distance.Text = distance;
                if ~isnan(str2double(temperature))
                    app.TemperatureGauge.Value = str2double(temperature);
                end
                if ~isnan(str2double(coolantLevel))
                    app.CoolantLevelGauge.Value = str2double(coolantLevel);
                end
                if ~isnan(str2double(motorSpeed))
                    app.MotorSpeedGauge.Value = str2double(motorSpeed);
                end

                if red == "1"
                    app.red.Color = "red";
                else
                    app.red.Color = "white";
                end

                if yellow == "1"
                    app.yellow.Color = "yellow";
                else
                    app.yellow.Color =  "white";
                end

                if green == "1"
                    app.green.Color = "green"
                else
                    app.green.Color = "white";
                end

                if temperatureAlarm == "1"
                    app.tempAlarm.Color = "red";
                else
                    app.tempAlarm.Color = "white";
                end

                if coolantLevelAlarm == "1"
                    app.coolAlarm.Color = "red";
                else
                    app.coolAlarm.Color = "white";
                end
                
                if headlightStatus == "0.5"
                    app.headlightStatus.Text = "DIM";
                elseif headlightStatus == "1.0"
                    app.headlightStatus.Text = "ON";
                else headlightStatus == "0.0"
                    app.headlightStatus.Text = "OFF";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MotorSpeedGaugeLabel = uilabel(app.UIFigure);
            app.MotorSpeedGaugeLabel.Text = 'Motor Speed (%)';
            app.MotorSpeedGauge = uigauge(app.UIFigure, 'circular');
            app.green = uilamp(app.UIFigure);
            app.yellow = uilamp(app.UIFigure);
            app.red = uilamp(app.UIFigure);
            app.distanceString = uilabel(app.UIFigure);
            app.distanceString.Text = 'Distance (m): ';
            app.distance = uilabel(app.UIFigure);
            app.distance.Text = 'N/A';
            app.CoolantLevelGaugeLabel = uilabel(app.UIFigure);
            app.CoolantLevelGaugeLabel.Text = 'Coolant Level';
            app.CoolantLevelGauge = uigauge(app.UIFigure, 'semicircular');
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.UIFigure, 'linear');
            app.tempAlarm = uilamp(app.UIFigure);
            app.coolAlarm = uilamp(app.UIFigure);
            app.headlight = uilabel(app.UIFigure);
            app.headlight.Text = 'Headlight Status: ';
            app.headlightStatus = uilabel(app.UIFigure);
            app.headlightStatus.Text = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cag383_FinalProjectUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end